classdef ES3H3_Sign_Language_game_ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        AmericanSignLanguagePanel  matlab.ui.container.Panel
        Image                      matlab.ui.control.Image
        BackButton                 matlab.ui.control.Button
        roundLabel                 matlab.ui.control.Label
        StatusLabel                matlab.ui.control.Label
        outputLabel                matlab.ui.control.Label
        PointsLabel                matlab.ui.control.Label
        ShowmetheletterLabel       matlab.ui.control.Label
        StartGameButton            matlab.ui.control.StateButton
        ConfirmGuessButton         matlab.ui.control.Button
        AnswerKeyButton            matlab.ui.control.Button
        Switch                     matlab.ui.control.Switch
        ConfidenceLabel            matlab.ui.control.Label
        ModelLabel                 matlab.ui.control.Label
        PredictionLabel            matlab.ui.control.Label
        SelectNetworkButtonGroup   matlab.ui.container.ButtonGroup
        GooglenetButton            matlab.ui.control.RadioButton
        Resnet50Button             matlab.ui.control.RadioButton
        AlexnetButton              matlab.ui.control.RadioButton
        VGG19Button                matlab.ui.control.RadioButton
        VGG16Button                matlab.ui.control.RadioButton
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        webcamObject
        imageObject
        VGG19vowels = load('vgg19_network_vowels_.mat'); 
        VGG16vowels = load('vgg16_network_vowels_.mat');
        Alexnetvowels = load('Alexnet_network_vowels_.mat');
        Resnet50vowels = load('resnet50_vowels_FINAL_REAL.mat');
        GooglenetVowels = load('googlenet_network_vowels.mat');

        VGG16Alphabet = load('vgg16_network_all_.mat')
        VGG19Alphabet = load('vgg19_full_alphabet_.mat') 
        AlexnetAlphabet = load('Alexnet_network_all_.mat')
        Resnet50Alphabet = load('resnet50_a2z_FINAL_REAL.mat')
        GooglenetAlphabet = load("googlenet_network.mat")

        handsnet
        scores
        label
        className
        Layers
        InputSize
        model
        val


        % for game
        % vowels = 'aeiou'
        % alphabets = 'abcdefghijklmnopqrstuvwxyz'
        vowels = 'AEIOU'
        alphabets = 'ABCDEFGHIJKLMNOPQRSTUVWXYZ'
        vowel
        alphabet
        index
        show
        points = 0
        round
        gameOn
    end
    
    methods (Access = public)

        function UpdateImageButton(app)
            clear('cam');
            cam = webcam;
            img = snapshot(cam);
           if strcmp(app.Switch.Value, 'Vowels') == 1
                if app.VGG16Button.Value == true
                    img = imresize(img, app.VGG16vowels.handsnet.Layers(1).InputSize(1:2));
                    app.scores = max(predict(app.VGG16vowels.handsnet, img), [], 2);
                    app.label = classify(app.VGG16vowels.handsnet,img);
                    app.model = 'VGG-16';

                elseif app.VGG19Button.Value == true
                    img = imresize(img, app.VGG19vowels.handsnet.Layers(1).InputSize(1:2));
                    app.scores = max(predict(app.VGG19vowels.handsnet, img), [], 2);
                    app.label = classify(app.VGG19vowels.handsnet,img);
                    app.model = 'VGG-19';

                elseif app.AlexnetButton.Value == true
                    img = imresize(img, app.Alexnetvowels.handsnet.Layers(1).InputSize(1:2));
                    app.scores = max(predict(app.Alexnetvowels.handsnet, img), [], 2);
                    app.label = classify(app.Alexnetvowels.handsnet,img);
                    app.model = 'Alexnet';

                elseif app.Resnet50Button.Value == true
                    img = imresize(img, app.Resnet50vowels.handsnet.Layers(1).InputSize(1:2));
                    app.scores = max(predict(app.Resnet50vowels.handsnet, img), [], 2);
                    app.label = classify(app.Resnet50vowels.handsnet,img);
                    app.model = 'Resnet-50';

                elseif app.GooglenetButton.Value == true
                    img = imresize(img, app.GooglenetVowels.handsnet.Layers(1).InputSize(1:2));
                    app.scores = max(predict(app.GooglenetVowels.handsnet, img), [], 2);
                    app.label = classify(app.GooglenetVowels.handsnet,img);
                    app.model = 'Googlenet';

                end
            end

            if strcmp(app.Switch.Value, 'Alphabet') == 1
                if app.VGG16Button.Value == true
                    img = imresize(img, app.VGG16Alphabet.handsnet.Layers(1).InputSize(1:2));
                    app.scores = max(predict(app.VGG16Alphabet.handsnet, img), [], 2);
                    app.label = classify(app.VGG16Alphabet.handsnet,img);
                    app.model = 'VGG-16';

                elseif app.VGG19Button.Value == true
                    img = imresize(img, app.VGG19Alphabet.handsnet.Layers(1).InputSize(1:2));
                    app.scores = max(predict(app.VGG19Alphabet.handsnet, img), [], 2);
                    app.label = classify(app.VGG19Alphabet.handsnet,img);
                    app.model = 'VGG-19';

                elseif app.AlexnetButton.Value == true
                    img = imresize(img, app.AlexnetAlphabet.handsnet.Layers(1).InputSize(1:2));
                    app.scores = max(predict(app.AlexnetAlphabet.handsnet, img), [], 2);
                    app.label = classify(app.AlexnetAlphabet.handsnet,img);
                    app.model = 'Alexnet';

                elseif app.Resnet50Button.Value == true
                    img = imresize(img, app.Resnet50Alphabet.handsnet.Layers(1).InputSize(1:2));
                    app.scores = max(predict(app.Resnet50Alphabet.handsnet, img), [], 2);
                    app.label = classify(app.Resnet50Alphabet.handsnet,img);
                    app.model = 'Resnet-50';

                elseif app.GooglenetButton.Value == true
                    img = imresize(img, app.GooglenetAlphabet.handsnet.Layers(1).InputSize(1:2));
                    app.scores = max(predict(app.GooglenetAlphabet.handsnet, img), [], 2);
                    app.label = classify(app.GooglenetAlphabet.handsnet,img);
                    app.model = 'Googlenet';
                end
            end

            if strcmp(char(app.label), 'Background - all') || strcmp(char(app.label), 'background - all')
                app.val = 'No Gesture Detected';
            else
                app.val = append('Prediction: ', upper(extract(char(app.label),1)));
            end
        end

        function SignlanguageShown(app)
            app.ConfidenceLabel.Text = append('Confidence: ', num2str(round(100*app.scores,2)),'%');
            app.ModelLabel.Text = append('Model: ' ,app.model);
            app.PredictionLabel.Text = app.val;
        end

        function WebcamOn(app)
            app.webcamObject = webcam(1);
            app.imageObject = image(app.UIAxes);
            axis(app.UIAxes)
            app.UIAxes.YDir = 'reverse';
            app.webcamObject.preview(app.imageObject);
            SignlanguageShown(app)            
        end
             
        function TakePicture(app)
            cam = webcam;
            app.show = upper(extract(char(app.label),1));
        end

        function LiveVideo(app)
            WebcamOn(app)
            while true
                UpdateImageButton(app)
                SignlanguageShown(app)
                pause(0.01)
                drawnow;
            end
        end

        function Game(app)
            app.roundLabel.Text = append('Round: ', string(app.round));
            if app.round < 5
                if strcmp(app.Switch.Value, 'Vowels')
                    if isfield(app, 'vowel')  % Check if 'vowel' field exists
                        lastVowel = app.vowel;  % Store the last vowel
                    else
                        lastVowel = '';
                    end
                    % Generate a new vowel that is different from the last one
                    while true
                        app.index = randi(length(app.vowels));  % Generate a random index
                        app.vowel = app.vowels(app.index);  % Select the vowel at the random index
                        if ~strcmp(app.vowel, lastVowel)  % If the new vowel is different from the last one, break the loop
                            break;
                        end
                    end

                    app.ShowmetheletterLabel.Text = append('Show me the letter: ', app.vowel);
                else
                    if isfield(app, 'alphabet')  % Check if 'vowel' field exists
                        lastAlphabet = app.alphabet;  % Store the last vowel
                    else
                        lastAlphabet = '';
                    end

                    while true
                        app.index = randi(length(app.alphabets));  % Generate a random index
                        app.alphabet = app.alphabets(app.index);  % Select the vowel at the random index
                        if ~strcmp(app.alphabet, lastAlphabet)  % If the new vowel is different from the last one, break the loop
                            break;
                        end
                    end

                    app.ShowmetheletterLabel.Text = append('Show me the letter: ', app.alphabet);

                end


                app.ConfirmGuessButton.Visible = 'on';

            else

                if app.points >= 30
                    app.StatusLabel.Text = 'Congratulations, you won!';
                else
                    app.StatusLabel.Text = 'You lost, try again!';
                end
                app.roundLabel.Visible = 'off';
                app.ShowmetheletterLabel.Text = 'Game Completed';
                app.round = 0;
                % app.points = 0;
                app.StatusLabel.Text = ' ';
                app.PointsLabel.Text = append('You got: ',string(app.points), ' in total !');
                pause(0.3)
                app.PointsLabel.Visible = 'off';
                pause(0.3)
                app.PointsLabel.Visible = 'on';
                pause(0.3)
                app.PointsLabel.Visible = 'off';
                pause(0.3)
                app.PointsLabel.Visible = 'on';
                app.ConfirmGuessButton.Enable = 'off';
                

                pause(3)
                app.ShowmetheletterLabel.Visible = 'off';
                app.PointsLabel.Visible = 'off';
                app.outputLabel.Visible = 'off';
                app.StatusLabel.Visible = 'off';
                app.roundLabel.Visible = 'off';
                app.StartGameButton.Enable = 'on';

                app.roundLabel.Text = '1';
                app.PointsLabel.Text = '0';
                app.gameOn = 0;


            end
        end

        
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear cam           
            app.webcamObject = webcam(1);
            app.imageObject = snapshot(app.webcamObject);
            UpdateImageButton(app)
            LiveVideo(app)
            app.ConfirmGuessButton.Enable = 'off';

            app.ShowmetheletterLabel.Visible = 'off';
            app.PointsLabel.Visible = 'off';
            app.outputLabel.Visible = 'off';
            app.roundLabel.Visible = 'off';

       
        end

        % Selection changed function: SelectNetworkButtonGroup
        function SelectNetworkButtonGroupSelectionChanged(app, event)
            UpdateImageButton(app)
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            UpdateImageButton(app)
        end

        % Button pushed function: AnswerKeyButton
        function AnswerKeyButtonPushed(app, event)
            app.AmericanSignLanguagePanel.Position = [1 -17 762 632];
            
            % app.ConfirmGuessButton.Visible = 'off';
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.AmericanSignLanguagePanel.Position = [1 -601 762 571];
        end

        % Button pushed function: ConfirmGuessButton
        function ConfirmGuessButtonPushed(app, event)
            TakePicture(app)

            if strcmp(char(app.label), 'Background - all') || strcmp(char(app.label), 'background - all')
                app.show = 'Nothing';
            else
                app.show = upper(extract(char(app.label),1));
            end
            app.outputLabel.Text = append('captured: ',app.show);
            if strcmp(app.Switch.Value, 'Vowels')
                if strcmp(app.show,app.vowel)
                    app.points = app.points + 10;
                    app.PointsLabel.Text = string(app.points);
                    app.StatusLabel.Text = 'Correct ! ';
                    app.StatusLabel.Visible = 0;
                    pause(0.2)
                    app.StatusLabel.Visible = 1;
                    pause(0.3)
                    app.StatusLabel.Visible = 0;
                    pause(0.4)
                    app.StatusLabel.Visible = 1;
                else 
                    app.StatusLabel.Text = 'Not quite, try again ';
                    app.StatusLabel.Visible = 0;
                    pause(0.2)
                    app.StatusLabel.Visible = 1;
                    pause(0.3)
                    app.StatusLabel.Visible = 0;
                    pause(0.4)
                    app.StatusLabel.Visible = 1;
                    app.PointsLabel.Text = string(app.points);
                end
            else
                if strcmp(app.show,app.alphabet)
                    app.points = app.points + 10;
                    app.PointsLabel.Text = string(app.points);
                    app.StatusLabel.Text = 'Correct ! ';
                    app.StatusLabel.Visible = 0;
                    pause(0.2)
                    app.StatusLabel.Visible = 1;
                    pause(0.3)
                    app.StatusLabel.Visible = 0;
                    pause(0.4)
                    app.StatusLabel.Visible = 1;
                else 
                    app.StatusLabel.Text = 'Not quite, try again ';
                    app.StatusLabel.Visible = 0;
                    pause(0.2)
                    app.StatusLabel.Visible = 1;
                    pause(0.3)
                    app.StatusLabel.Visible = 0;
                    pause(0.4)
                    app.StatusLabel.Visible = 1;
                    app.PointsLabel.Text = string(app.points);
                end
            end 
            pause(1)
            if app.round < 5
                app.roundLabel.Visible = 'on';
                app.round = app.round + 1;
            else
                app.roundLabel.Visible = 'off';
            end
            Game(app)
        end

        % Value changed function: StartGameButton
        function StartGameButtonValueChanged(app, event)
            % value = app.StartGameButton.Value;
            app.round = 1;
            app.points = 0;
            app.ShowmetheletterLabel.Visible = 'on';
            app.PointsLabel.Visible = 'on';
            app.ConfirmGuessButton.Enable = 'on';
            app.outputLabel.Visible = 'on';
            app.StatusLabel.Visible = 'on';
            Game(app)
            app.StartGameButton.Enable = 'off';  
            app.roundLabel.Visible = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.SelectNetworkButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectNetworkButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectNetworkButtonGroupSelectionChanged, true);
            app.SelectNetworkButtonGroup.Title = 'Select Network';
            app.VGG16Button = uiradiobutton(app.SelectNetworkButtonGroup);
            app.VGG16Button.Text = 'VGG16';
            app.VGG19Button = uiradiobutton(app.SelectNetworkButtonGroup);
            app.VGG19Button.Text = 'VGG19';
            app.AlexnetButton = uiradiobutton(app.SelectNetworkButtonGroup);
            app.AlexnetButton.Text = 'Alexnet';
            app.Resnet50Button = uiradiobutton(app.SelectNetworkButtonGroup);
            app.Resnet50Button.Text = 'Resnet50';
            app.Resnet50Button.Value = true;
            app.GooglenetButton = uiradiobutton(app.SelectNetworkButtonGroup);
            app.GooglenetButton.Text = 'Googlenet';
            app.PredictionLabel = uilabel(app.UIFigure);
            app.PredictionLabel.Text = 'Prediction';
            app.ModelLabel = uilabel(app.UIFigure);
            app.ModelLabel.Text = 'Model';
            app.ConfidenceLabel = uilabel(app.UIFigure);
            app.ConfidenceLabel.Text = 'Confidence';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Vowels', 'Alphabet'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Vowels';
            app.AnswerKeyButton = uibutton(app.UIFigure, 'push');
            app.AnswerKeyButton.ButtonPushedFcn = createCallbackFcn(app, @AnswerKeyButtonPushed, true);
            app.AnswerKeyButton.Text = 'Answer Key';
            app.ConfirmGuessButton = uibutton(app.UIFigure, 'push');
            app.ConfirmGuessButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmGuessButtonPushed, true);
            app.ConfirmGuessButton.Enable = 'off';
            app.ConfirmGuessButton.Text = 'Confirm Guess';
            app.StartGameButton = uibutton(app.UIFigure, 'state');
            app.StartGameButton.ValueChangedFcn = createCallbackFcn(app, @StartGameButtonValueChanged, true);
            app.StartGameButton.Text = 'Start Game';
            app.ShowmetheletterLabel = uilabel(app.UIFigure);
            app.ShowmetheletterLabel.Visible = 'off';
            app.ShowmetheletterLabel.Text = 'Show me the letter: ';
            app.PointsLabel = uilabel(app.UIFigure);
            app.PointsLabel.Visible = 'off';
            app.PointsLabel.Text = 'Points';
            app.outputLabel = uilabel(app.UIFigure);
            app.outputLabel.Visible = 'off';
            app.outputLabel.Text = 'output';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Visible = 'off';
            app.StatusLabel.Text = 'Status';
            app.roundLabel = uilabel(app.UIFigure);
            app.roundLabel.Visible = 'off';
            app.roundLabel.Text = 'round';
            app.AmericanSignLanguagePanel = uipanel(app.UIFigure);
            app.AmericanSignLanguagePanel.Title = 'American Sign Language ';
            app.BackButton = uibutton(app.AmericanSignLanguagePanel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.Image = uiimage(app.AmericanSignLanguagePanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'ASL alphabet v1.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ES3H3_Sign_Language_game_

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end